classdef EEG_visualize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Label_5         matlab.ui.control.Label
        Label_4         matlab.ui.control.Label
        Label_3         matlab.ui.control.Label
        Label_2         matlab.ui.control.Label
        Label           matlab.ui.control.Label
        PreviousButton  matlab.ui.control.Button
        NextButton      matlab.ui.control.Button
        UIAxes_5        matlab.ui.control.UIAxes
        UIAxes_2        matlab.ui.control.UIAxes
        UIAxes_3        matlab.ui.control.UIAxes
        UIAxes_4        matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        start_index=1
        EEG_graph
    end  
    
    methods (Access = private)
      
        function updatePlot(app)
            
            plots=[app.UIAxes,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5];
            labels=[app.Label,app.Label_2,app.Label_3,app.Label_4,app.Label_5];

            for ii = 1:5
                cla(plots(ii));
                labels(ii).Text=''
            end

            for ii = app.start_index:min(app.start_index+4,size(app.EEG_graph,1))
                disp(ii-app.start_index+1)
                disp(ii)
                plot(plots(ii-app.start_index+1),app.EEG_graph(ii, :))
                labels(ii-app.start_index+1).Text=compose('Essai %d',ii)
            end
        
        
        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Chargez la matrice EEG à partir du workspace
            app.EEG_graph = evalin('base', 'EEG');
            updatePlot(app)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)

            if app.start_index+5<size(app.EEG_graph,1)            
                app.start_index= app.start_index+5;
            end

            updatePlot(app);
            
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.start_index=max(app.start_index-5,1);
            updatePlot(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '<EEG>')
            ylabel(app.UIAxes, 'µV')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_4, 'µV')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_3, 'µV')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_2, 'µV')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_5, 'Time (s)')
            ylabel(app.UIAxes_5, 'µV')
            zlabel(app.UIAxes_5, 'Z')
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.Label = uilabel(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_5 = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_visualize

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end